classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        PlotmaxreachCheckBox           matlab.ui.control.CheckBox
        PlotpointcloudCheckBox         matlab.ui.control.CheckBox
        ContinueButton                 matlab.ui.control.Button
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        RobotArm1Label                 matlab.ui.control.Label
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        RobotArm2Label                 matlab.ui.control.Label
        XEditField_2Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        HousingTopLabel                matlab.ui.control.Label
        XEditField_3Label              matlab.ui.control.Label
        XEditField_3                   matlab.ui.control.NumericEditField
        YEditField_3Label              matlab.ui.control.Label
        YEditField_3                   matlab.ui.control.NumericEditField
        ZEditField_3Label              matlab.ui.control.Label
        ZEditField_3                   matlab.ui.control.NumericEditField
        HousingBottomLabel             matlab.ui.control.Label
        XEditField_4Label              matlab.ui.control.Label
        XEditField_4                   matlab.ui.control.NumericEditField
        YEditField_4Label              matlab.ui.control.Label
        YEditField_4                   matlab.ui.control.NumericEditField
        ZEditField_4Label              matlab.ui.control.Label
        ZEditField_4                   matlab.ui.control.NumericEditField
        CircuitBoardLabel              matlab.ui.control.Label
        XEditField_5Label              matlab.ui.control.Label
        XEditField_5                   matlab.ui.control.NumericEditField
        YEditField_5Label              matlab.ui.control.Label
        YEditField_5                   matlab.ui.control.NumericEditField
        ZEditField_5Label              matlab.ui.control.Label
        ZEditField_5                   matlab.ui.control.NumericEditField
        CrateLabel                     matlab.ui.control.Label
        XEditField_6Label              matlab.ui.control.Label
        XEditField_6                   matlab.ui.control.NumericEditField
        YEditField_6Label              matlab.ui.control.Label
        YEditField_6                   matlab.ui.control.NumericEditField
        ZEditField_6Label              matlab.ui.control.Label
        CLEARALLButton                 matlab.ui.control.Button
        ZEditField_6                   matlab.ui.control.NumericEditField
        StartGettingMaxValueButton     matlab.ui.control.Button
        StartSimulationofpartsButton   matlab.ui.control.Button
        LoadpointcloudCheckBox         matlab.ui.control.CheckBox
        pointCloudstepsEditFieldLabel  matlab.ui.control.Label
        pointCloudstepsEditField       matlab.ui.control.NumericEditField
        LoadVariablesButton            matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        StopatwaitpointsCheckBox       matlab.ui.control.CheckBox
        UpdataposesButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        log;
        UR3_1;
        UR3_2;
        Table;
        Fence1;
        Fence2;
        Fence2_2;
        Fence3;
        Fence4;
        Fence4_4;
        HousingTop;
        HousingBottom;
        CircuitBoard;
        Crate;
        bodies;
        objects;
        Max_Reach;
        Max_Volume;
    end
    
    methods (Access = public)
    end
    
    methods (Access = private)
        
        
        function results = setup(app)
            %% modelling UR3 Robots
            app.log = logger('log.txt');
            hold on
            floorOffset = -0.8905/2; %messured from bounding box
            workSize = 1;
            workspace = [-workSize workSize -workSize workSize (2*floorOffset) workSize];
            
            % import arms
            app.UR3_1 = UR3Model('UR3_1',workspace, transl(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value), 1);
            app.UR3_2 = UR3Model('UR3_2',workspace, transl(app.XEditField_2.Value,app.YEditField_2.Value,app.ZEditField_2.Value), 1);
            
            out = ( app.UR3_1.model.base(1:2,4) + app.UR3_2.model.base(1:2,4) ) .'/2;
            safeDistance = 1.5;
            fenceOffset = 1.35
            tableOffset = 0.5
            % import objects
            % fix translations
            app.Table = Objects('table','1',workspace,transl(out(1), out(2),floorOffset));
            app.Fence1 = Objects('fence','1',workspace,transl(out(1),out(2) + safeDistance,floorOffset));
            app.Fence2 = Objects('fence','2',workspace,transl(out(1) - tableOffset + safeDistance,out(2) + fenceOffset/2,floorOffset) * trotz(pi/2));
            app.Fence2_2 = Objects('fence','2_2',workspace,transl(out(1) - tableOffset + safeDistance,out(2) - fenceOffset/2,floorOffset) * trotz(pi/2));
            app.Fence3 = Objects('fence','3',workspace,transl(out(1),out(2) - safeDistance,floorOffset) * trotz(pi));
            app.Fence4 = Objects('fence','4',workspace,transl(out(1) + tableOffset - safeDistance,out(2) + fenceOffset/2,floorOffset) * trotz((3 *pi)/2));
            app.Fence4_4 = Objects('fence','4_4',workspace,transl(out(1) + tableOffset - safeDistance,out(2) - fenceOffset/2,floorOffset) * trotz((3 *pi)/2));
            app.HousingTop = Objects('housingTop','1',workspace,transl(app.XEditField_3.Value,app.YEditField_3.Value,app.ZEditField_3.Value));
            app.HousingBottom = Objects('housingBottom','1',workspace,transl(app.XEditField_4.Value,app.YEditField_4.Value,app.ZEditField_4.Value));
            app.CircuitBoard = Objects('circuitBoard','1',workspace,transl(app.XEditField_5.Value,app.YEditField_5.Value,app.ZEditField_5.Value));
            app.Crate = Objects('Crate','1',workspace,transl(app.XEditField_6.Value,app.YEditField_6.Value,app.ZEditField_6.Value));
            
            pause(0.01);
            % bodies = [UR3_1,UR3_1,Table,Fence1,Fence2,Fence2_2,Fence3,Fence4,Fence4_4,HousingTop,HousingBottom,CircuitBoard,Crate];
            app.bodies = [app.UR3_1,app.UR3_2]
            app.objects = [app.HousingTop,app.HousingBottom,app.CircuitBoard,app.Crate];
            %log bodies
            app.log.firstLogRobot(app.bodies);
            app.log.firstLogRobot(app.objects);
        end
        
        
        function waiting(app)            
            if app.StopatwaitpointsCheckBox.Value == 1
                app.Lamp.Color = [0,1,0];
                uiwait
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pointCloudstepsEditField.Value = 45;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.setup();
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.Lamp.Color = [1,1,1];
            uiresume
        end

        % Button pushed function: CLEARALLButton
        function CLEARALLButtonPushed(app, event)
            close all
            set(0,'DefaultFigureWindowStyle','docked')
            clear
            clc
        end

        % Button pushed function: StartGettingMaxValueButton
        function StartGettingMaxValueButtonPushed(app, event)
            %% finding max reach Point cloud
            
            % prompt = 'Select a robot model; MDL = 1 PERSONAL = 2: ';
            % chunk = input(prompt);
            chunk = app.LoadpointcloudCheckBox.Value;
            switch chunk
                % 2.4 Sample the joint angles within the joint limits at 30 degree increments between each of the joint limits
                % & 2.5 Use fkine to determine the point in space for each of these poses, so that you end up with a big list of points
                case 0
                    pointCloud = app.UR3_1.GeneratePointCloud(app.pointCloudstepsEditField.Value);
                case 1
                    pointCloud = app.UR3_1.LoadPointCloud();
            end
            
            %% Finding max reach
            
            [Reach,Index] = app.UR3_1.MaxRobotReach();
            
            app.Max_Reach = Reach
            display(['Max Reach is: in m ',num2str(app.Max_Reach)])
            
            
            chunk = app.PlotmaxreachCheckBox.Value;
            switch chunk
                % 2.4 Sample the joint angles within the joint limits at 30 degree increments between each of the joint limits
                % & 2.5 Use fkine to determine the point in space for each of these poses, so that you end up with a big list of points
                case 0
                    
                case 1
                    app.UR3_1.model.animate(app.UR3_1.qValueMatrix(Index,:));
                    app.waiting
            end
            
            %% finding max reach
            
            app.Max_Volume = app.UR3_1.MaxRobotVolume()
            display(['Max volume is: in m^3 ',num2str(app.Max_Volume)])
            %% plot point cloud
            
            
            chunk = app.PlotpointcloudCheckBox.Value;
            switch chunk
                % 2.4 Sample the joint angles within the joint limits at 30 degree increments between each of the joint limits
                % & 2.5 Use fkine to determine the point in space for each of these poses, so that you end up with a big list of points
                case 0
                    
                case 1
                    % plot from tutorials
                    pointCloudPlot = plot3(pointCloud(:,1),pointCloud(:,2),pointCloud(:,3),'r.');
                    app.waiting
                    delete(pointCloudPlot)
            end
            
            %% log section
            
            app.log.MaxLog(app.UR3_1);
            
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
        end

        % Button pushed function: StartSimulationofpartsButton
        function StartSimulationofpartsButtonPushed(app, event)
            handoffLocation = ((app.UR3_1.model.base*app.UR3_2.model.base)/2);
            handoffLocation = handoffLocation(1:3,4);
            if (max(handoffLocation)/2) < 0.3
                handoffLocation = transl(handoffLocation) * transl(0,0,0.3);
            else
                handoffLocation = transl(handoffLocation) * transl(0,0,max(handoffLocation)/2);
            end
            
            handoffLocation = handoffLocation * trotx(pi/2) * troty(0);
            
            %             housing_top_pose = HousingTop.model.base * trotx(pi)
            %             housing_bottom_pose = HousingBottom.model.base * trotx(pi)
            %             circuit_board_pose = CircuitBoard.model.base * trotx(pi)
            
            hold on
%             initQ = [pi/2,]
            
            % animate 1, pick up top Housing
            % going to housing top carrying nothing with arm one
            MoveWObjects(app.UR3_1,app.HousingTop.model.base, []);
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
            app.waiting
            % animate 2, pick up bottom Housing
            % going to housing bottom carrying nothing with arm two
            MoveWObjects(app.UR3_2,app.CircuitBoard.model.base, []);
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
            app.waiting
            % animate 3, go to meeting location to put parts together
            % carrying top housing with arm one
%             MoveWObjects(app.UR3_1,[0,-pi/4,pi/2,0,0,0], [app.HousingTop]);
            MoveWObjects(app.UR3_1,handoffLocation, [app.HousingTop]);
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
            app.waiting
            % animate 4, go to UR3_1 pose location to put parts together
            % carrying bottom housing with arm two
            MoveWObjects(app.UR3_2,[0,-(3*pi)/4,pi/2,0,0,0], [app.CircuitBoard])
            MoveWObjects(app.UR3_2,app.UR3_1.model.fkine(app.UR3_1.model.getpos)* trotz(pi), [app.CircuitBoard]);
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
            app.waiting
            % animate 5, go to circuit pose location to pickup
            % carrying nothing with arm two
            % go back to init position
%             MoveWObjects(app.UR3_2,[-pi,-pi/2,pi/2,0,0,0], []);
            MoveWObjects(app.UR3_2,app.HousingBottom.model.base, []);
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
            app.waiting
            % animate 6, go to UR3_1 pose location to put parts together
            % carrying bottom housing with arm two
            MoveWObjects(app.UR3_2,app.UR3_1.model.fkine(app.UR3_1.model.getpos)* trotz(pi), [app.HousingBottom]);
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
            app.waiting
            % animate 6, go to crate pose to dump parts
            % carrying bottom housing top housing and circuit board
            Qpass = app.UR3_1.model.getpos;
            Qpass(2) = -pi/2;
            MoveWObjects(app.UR3_1,[-pi,-pi/2,pi/2,0,0,0], [app.HousingTop, app.HousingBottom, app.CircuitBoard]);
            MoveWObjects(app.UR3_1,app.Crate.model.base * transl(0,0,0.1), [app.HousingTop, app.HousingBottom, app.CircuitBoard]);
            app.log.logging(app.bodies);
            app.log.logging(app.objects);
            app.waiting
            %template
            % MoveWObjects(UR3_1,transl(0,0,0), [HousingTop, HousingBottom])
        end

        % Button pushed function: LoadVariablesButton
        function LoadVariablesButtonPushed(app, event)
            app.XEditField.Value = -0.23;
            app.YEditField.Value = 0.23;
            app.ZEditField.Value = 0;
            
            app.XEditField_2.Value = 0.23;
            app.YEditField_2.Value = 0.23;
            app.ZEditField_2.Value = 0;
            
            app.XEditField_3.Value = -0.42;
            app.YEditField_3.Value = 0.46;
            app.ZEditField_3.Value = 0;
            
            app.XEditField_4.Value = 0.42;
            app.YEditField_4.Value = 0.46;
            app.ZEditField_4.Value = 0;
            
            app.XEditField_5.Value = 0.2;
            app.YEditField_5.Value = 0.5;
            app.ZEditField_5.Value = 0;
            
            app.XEditField_6.Value = -0.05;
            app.YEditField_6.Value = -0.1;
            app.ZEditField_6.Value = 0;
            
        end

        % Button pushed function: UpdataposesButton
        function UpdataposesButtonPushed(app, event)
            pos = app.UR3_1.model.fkine(app.UR3_1.model.getpos);
            pos = pos(1:3,4);
            app.XEditField.Value = pos(1);
            app.YEditField.Value = pos(2);
            app.ZEditField.Value = pos(3);
            
            pos = app.UR3_2.model.fkine(app.UR3_2.model.getpos);
            pos = pos(1:3,4);
            app.XEditField_2.Value = pos(1);
            app.YEditField_2.Value = pos(2);
            app.ZEditField_2.Value = pos(3);
            
            pos = app.HousingTop.model.fkine(app.HousingTop.model.getpos);
            pos = pos(1:3,4);
            app.XEditField_3.Value = pos(1);
            app.YEditField_3.Value = pos(2);
            app.ZEditField_3.Value = pos(3);
            
            pos = app.HousingBottom.model.fkine(app.HousingBottom.model.getpos);
            pos = pos(1:3,4);
            app.XEditField_4.Value = pos(1);
            app.YEditField_4.Value = pos(2);
            app.ZEditField_4.Value = pos(3);
            
            pos = app.CircuitBoard.model.fkine(app.CircuitBoard.model.getpos);
            pos = pos(1:3,4);
            app.XEditField_5.Value = pos(1);
            app.YEditField_5.Value = pos(2);
            app.ZEditField_5.Value = pos(3);
            
            pos = app.Crate.model.fkine(app.Crate.model.getpos);
            pos = pos(1:3,4);
            app.XEditField_6.Value = pos(1);
            app.YEditField_6.Value = pos(2);
            app.ZEditField_6.Value = pos(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PlotmaxreachCheckBox = uicheckbox(app.UIFigure);
            app.PlotmaxreachCheckBox.Text = 'Plot max reach';
            app.PlotpointcloudCheckBox = uicheckbox(app.UIFigure);
            app.PlotpointcloudCheckBox.Text = 'Plot point cloud';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.RobotArm1Label = uilabel(app.UIFigure);
            app.RobotArm1Label.Text = 'Robot Arm 1';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RobotArm2Label = uilabel(app.UIFigure);
            app.RobotArm2Label.Text = 'Robot Arm 2';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HousingTopLabel = uilabel(app.UIFigure);
            app.HousingTopLabel.Text = 'Housing Top';
            app.XEditField_3Label = uilabel(app.UIFigure);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_3Label = uilabel(app.UIFigure);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_3Label = uilabel(app.UIFigure);
            app.ZEditField_3Label.Text = 'Z';
            app.ZEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.HousingBottomLabel = uilabel(app.UIFigure);
            app.HousingBottomLabel.Text = 'Housing Bottom';
            app.XEditField_4Label = uilabel(app.UIFigure);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_4Label = uilabel(app.UIFigure);
            app.YEditField_4Label.Text = 'Y';
            app.YEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_4Label = uilabel(app.UIFigure);
            app.ZEditField_4Label.Text = 'Z';
            app.ZEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.CircuitBoardLabel = uilabel(app.UIFigure);
            app.CircuitBoardLabel.Text = 'Circuit Board';
            app.XEditField_5Label = uilabel(app.UIFigure);
            app.XEditField_5Label.Text = 'X';
            app.XEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_5Label = uilabel(app.UIFigure);
            app.YEditField_5Label.Text = 'Y';
            app.YEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_5Label = uilabel(app.UIFigure);
            app.ZEditField_5Label.Text = 'Z';
            app.ZEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.CrateLabel = uilabel(app.UIFigure);
            app.CrateLabel.Text = 'Crate';
            app.XEditField_6Label = uilabel(app.UIFigure);
            app.XEditField_6Label.Text = 'X';
            app.XEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_6Label = uilabel(app.UIFigure);
            app.YEditField_6Label.Text = 'Y';
            app.YEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_6Label = uilabel(app.UIFigure);
            app.ZEditField_6Label.Text = 'Z';
            app.ZEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.CLEARALLButton = uibutton(app.UIFigure, 'push');
            app.CLEARALLButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARALLButtonPushed, true);
            app.CLEARALLButton.Text = 'CLEAR ALL';
            app.StartGettingMaxValueButton = uibutton(app.UIFigure, 'push');
            app.StartGettingMaxValueButton.ButtonPushedFcn = createCallbackFcn(app, @StartGettingMaxValueButtonPushed, true);
            app.StartGettingMaxValueButton.Text = 'Start Getting Max Value';
            app.StartSimulationofpartsButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationofpartsButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationofpartsButtonPushed, true);
            app.StartSimulationofpartsButton.Text = 'Start Simulation of parts';
            app.LoadpointcloudCheckBox = uicheckbox(app.UIFigure);
            app.LoadpointcloudCheckBox.Text = 'Load point cloud';
            app.pointCloudstepsEditFieldLabel = uilabel(app.UIFigure);
            app.pointCloudstepsEditFieldLabel.Text = 'pointCloud steps';
            app.pointCloudstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadVariablesButton = uibutton(app.UIFigure, 'push');
            app.LoadVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVariablesButtonPushed, true);
            app.LoadVariablesButton.Text = 'Load Variables';
            app.Lamp = uilamp(app.UIFigure);
            app.StopatwaitpointsCheckBox = uicheckbox(app.UIFigure);
            app.StopatwaitpointsCheckBox.Text = 'Stop at wait points';
            app.UpdataposesButton = uibutton(app.UIFigure, 'push');
            app.UpdataposesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdataposesButtonPushed, true);
            app.UpdataposesButton.Text = 'Updata poses';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end